classdef DiscreteConvolution_Dark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainApp                 matlab.ui.Figure
        signalPlot              matlab.ui.control.UIAxes
        NavigationPanel         matlab.ui.container.Panel
        Signal1Label            matlab.ui.control.Label
        Signal1DropDown         matlab.ui.control.DropDown
        Signal2DropDownLabel    matlab.ui.control.Label
        Signal2DropDown         matlab.ui.control.DropDown
        IndexnSpinnerLabel      matlab.ui.control.Label
        IndexnSpinner           matlab.ui.control.Spinner
        AmplitudeSliderLabel    matlab.ui.control.Label
        AmplitudeSlider_2       matlab.ui.control.Slider
        AmplitudeSlider_2Label  matlab.ui.control.Label
        AmplitudeSlider_1       matlab.ui.control.Slider
        WidthSpinnerLabel       matlab.ui.control.Label
        S1Spinner               matlab.ui.control.Spinner
        PowerSpinnerLabel       matlab.ui.control.Label
        S2Spinner               matlab.ui.control.Spinner
        outputPlot              matlab.ui.control.UIAxes
        signal2Plot             matlab.ui.control.UIAxes
        signal3Plot             matlab.ui.control.UIAxes
        SumEditFieldLabel       matlab.ui.control.Label
        SumEditField            matlab.ui.control.NumericEditField
        SumEditFieldLabel_2     matlab.ui.control.Label
    end

    
    properties (Access = private)
        X = linspace(-10,29,40)';   % X Axis values
        n = 0;                      % index n
        signal_1;
        signal_2;
        Y_n = zeros(40, 40);        % Each colomn is the Y of second signal at certain n
        output = zeros(40, 40);     
        s1, s2, s3, sO, selected;   % Objects of all plots 
    end
    
    methods (Access = private)
        
        % sig: signal to be calculated; either 1 or 2. 
        % Function calculates the signal [sig] that has changed based 
        % on the user's choice and recalculates the convolution.
        function calculate(app, sig)
        
            switch sig
                case 1   % Sugnal 1 has changed
                    app.S1Spinner.Enable = "On";
                    % A1 & A2 are amplitudes of sig 1 and 2
                    A1 = app.AmplitudeSlider_1.Value; 
                    switch app.Signal1DropDown.Value
                        case "Causal Exponential"
                            app.S1Spinner.Step = 0.05;
                            app.S1Spinner.Limits = [0.05 0.35];
                            app.WidthSpinnerLabel.Text = "Power";
                            S1 = app.S1Spinner.Value;
                            app.signal_1 = A1*exp(-S1*app.X);
              
                        case "Rectangular"
                            app.S1Spinner.Step = 1;
                            app.S1Spinner.Limits = [3 10];
                            app.WidthSpinnerLabel.Text ="Width";
                            S1 = app.S1Spinner.Value;
                            app.signal_1 = A1*rectangularPulse(-0.05, S1+0.5, app.X);
      
                        case "Sinc"
                            for i = 1:40
                                app.signal_1(i, 1) = sin(app.X(i))/app.X(i);
                            end
                            app.signal_1(11) = 1;
                            app.signal_1 = A1*app.signal_1;
                            app.S1Spinner.Enable = "Off";
                    end
                   
                case 2   % Signal 2 has changed
                    app.S2Spinner.Enable = "On";
                    app.AmplitudeSlider_2.Enable = "on";
                    A2 = app.AmplitudeSlider_2.Value;
                    switch app.Signal2DropDown.Value
                        case "Causal Exponential"
                            app.S2Spinner.Step = 0.05;
                            app.S2Spinner.Limits = [0.05 0.35];
                            app.PowerSpinnerLabel.Text = "Power";
                            S2 = app.S2Spinner.Value;
                            app.signal_2 = flip( A2*exp(-S2*app.X) );
                            
                        case "Rectangular"
                            app.S2Spinner.Step = 1;
                            app.S2Spinner.Limits = [3 10];
                            app.PowerSpinnerLabel.Text ="Width";
                            S2 = app.S2Spinner.Value;
                            app.signal_2 = flip( A2*rectangularPulse(-0.05, S2+0.5,app.X) );
                            
                        case "Sinc"
                            for i = 1:40
                                app.signal_2(i) = sin(app.X(i))/app.X(i);
                            end
                            app.signal_2(11) = 1;
                            app.signal_2 = A2*flip(app.signal_2)';
                            app.S2Spinner.Enable = "Off";
                            
                        case "Unit Impulse"   
                            app.signal_2 = (app.X == -10);
                            app.signal_2 = flip(app.signal_2);
                            app.AmplitudeSlider_2.Enable = "off";
                            app.S2Spinner.Enable = "Off";
                     end
                              
            end
            
            % Convolution and multiplied plots
            % output: Each coloumn has multiplication of x(n)h(n-k) [used
            % to plot figure 3].
            for i = 1 : 40
                app.Y_n(1:i, i)= app.signal_2(41 - i : end);
                app.output(:,i) = app.signal_1.*app.Y_n(:, i);
            end
            
        end
        
        % Refresh all plots and adjusts their limits
        function RefreshPlots(app)
        % Plot: signal 1 [x]
        app.signalPlot.YLim = [1.2*min(app.signal_1), 1.1*max(app.signal_1)];
        set(app.s1, 'YData', app.signal_1);
        % Plot: signal 2 [reversed and shifted] [h[n-k]]
        app.signal2Plot.YLim = [1.2* min(app.Y_n, [], 'all'), 1.1*max(app.Y_n, [], 'all')];
        set(app.s2, 'YData', app.Y_n(:, app.n+11));
        % Plot: multiplied x[n]h[n-k]
        app.signal3Plot.YLim = [1.2* min(app.output, [], 'all'), 1.1*max(app.output, [], 'all')];
        set(app.s3, 'YData', app.output(:, app.n+11));
        % Plot: Convolution output.
        set(app.sO, 'YData', sum(app.output));
        % Update selected point's value
        d = sum(app.output(:,app.n+11));
        set(app.selected, 'XData', app.n, 'YData', d', 'color', [0.93,0.07,0.31]);
        app.SumEditField.Value = d;
        drawnow
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        d = uiprogressdlg(app.mainApp,'Title','Please Wait','Message', 'Getting things ready',...
        'Indeterminate','on');
        % Using stem only in the startup so, it doesn't slow down the app.
        app.n = -10;  
        app.signal_1 = 0.5*rectangularPulse(-0.05, 3.5,app.X);
        calculate(app, 2);
        app.signalPlot.YLim = [0, 1.1*max(app.signal_1)];
        app.s1 = stem(app.signalPlot, app.X, app.signal_1, 'filled', 'color', [1.00,0.96,0.85]);
        app.signal2Plot.YLim = [0, 1.1*max(app.Y_n, [], 'all')];
        app.s2 = stem(app.signal2Plot, app.X, app.Y_n(:, app.n + 11), 'filled', 'color', [1.00,0.96,0.85]);
        app.signal3Plot.YLim = app.signal2Plot.YLim;
        app.s3 = stem(app.signal3Plot, app.X, app.output(:, app.n + 11), "filled", 'Color', [0.93,0.07,0.31]);
        app.sO = stem(app.outputPlot, app.X, sum(app.output)', "filled", 'Color', [1.00,0.96,0.85]);
        hold(app.outputPlot, 'on')
        app.selected = stem(app.outputPlot, app.n, sum(app.output(:,app.n+11))', 'filled', 'color', [0.93,0.07,0.31]);
        hold(app.outputPlot,"off")
        
        close(d);
        end

        % Value changed function: Signal1DropDown
        function Signal1DropDownValueChanged(app, event)
            calculate(app, 1);
            RefreshPlots(app);
        end

        % Value changed function: Signal2DropDown
        function Signal2DropDownValueChanged(app, event)
            calculate(app, 2);
            RefreshPlots(app);
        end

        % Value changed function: IndexnSpinner
        function IndexnSpinnerValueChanged(app, event)
            app.n = app.IndexnSpinner.Value;
            set(app.s2, 'YData', app.Y_n(:, app.n + 11));
            set(app.s3, 'YData', app.output(:,app.n + 11));
            d = sum(app.output(:,app.n+11));
            app.SumEditField.Value = d;
            set(app.selected, 'XData', app.n, 'YData', d', 'color', [0.93,0.07,0.31]);
            drawnow
        end

        % Value changed function: AmplitudeSlider_2
        function AmplitudeSlider_2ValueChanged(app, event)
        calculate(app, 2);
        RefreshPlots(app);
        end

        % Value changed function: AmplitudeSlider_1
        function AmplitudeSlider_1ValueChanged(app, event)
        calculate(app, 1);
        RefreshPlots(app);        
        end

        % Value changed function: S1Spinner
        function S1SpinnerValueChanged(app, event)
            calculate(app, 1);
            RefreshPlots(app);
        end

        % Value changed function: S2Spinner
        function S2SpinnerValueChanged(app, event)
         calculate(app, 2);
         RefreshPlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainApp = uifigure('Visible', 'off');
            app.mainApp.Name = 'Discrete Time Convolution';
            app.signalPlot = uiaxes(app.mainApp);
            title(app.signalPlot, '')
            xlabel(app.signalPlot, '')
            ylabel(app.signalPlot, 'x[n]')
            app.signalPlot.XLim = [-11 30];
            app.signalPlot.XTick = [-10 0 10 20 30];
            app.signalPlot.XTickLabel = {''; ''; ''; ''; ''};
            app.NavigationPanel = uipanel(app.mainApp);
            app.NavigationPanel.Title = 'Navigation ';
            app.Signal1Label = uilabel(app.NavigationPanel);
            app.Signal1Label.Text = 'Signal 1';
            app.Signal1DropDown = uidropdown(app.NavigationPanel);
            app.Signal1DropDown.Items = {'Causal Exponential', 'Rectangular', 'Sinc'};
            app.Signal1DropDown.ValueChangedFcn = createCallbackFcn(app, @Signal1DropDownValueChanged, true);
            app.Signal1DropDown.Value = 'Rectangular';
            app.Signal2DropDownLabel = uilabel(app.NavigationPanel);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.NavigationPanel);
            app.Signal2DropDown.Items = {'Causal Exponential', 'Rectangular', 'Sinc', 'Unit Impulse'};
            app.Signal2DropDown.ValueChangedFcn = createCallbackFcn(app, @Signal2DropDownValueChanged, true);
            app.Signal2DropDown.Value = 'Causal Exponential';
            app.IndexnSpinnerLabel = uilabel(app.NavigationPanel);
            app.IndexnSpinnerLabel.Text = 'Index (n)';
            app.IndexnSpinner = uispinner(app.NavigationPanel);
            app.IndexnSpinner.Limits = [-10 29];
            app.IndexnSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexnSpinnerValueChanged, true);
            app.IndexnSpinner.Editable = 'off';
            app.IndexnSpinner.Value = -10;
            app.AmplitudeSliderLabel = uilabel(app.NavigationPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider_2 = uislider(app.NavigationPanel);
            app.AmplitudeSlider_2.Limits = [0.5 0.95];
            app.AmplitudeSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_2ValueChanged, true);
            app.AmplitudeSlider_2.Value = 0.5;
            app.AmplitudeSlider_2Label = uilabel(app.NavigationPanel);
            app.AmplitudeSlider_2Label.Text = 'Amplitude';
            app.AmplitudeSlider_1 = uislider(app.NavigationPanel);
            app.AmplitudeSlider_1.Limits = [0.5 0.95];
            app.AmplitudeSlider_1.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_1ValueChanged, true);
            app.AmplitudeSlider_1.Value = 0.5;
            app.WidthSpinnerLabel = uilabel(app.NavigationPanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.S1Spinner = uispinner(app.NavigationPanel);
            app.S1Spinner.Limits = [3 10];
            app.S1Spinner.ValueChangedFcn = createCallbackFcn(app, @S1SpinnerValueChanged, true);
            app.S1Spinner.Value = 3;
            app.PowerSpinnerLabel = uilabel(app.NavigationPanel);
            app.PowerSpinnerLabel.Text = 'Power';
            app.S2Spinner = uispinner(app.NavigationPanel);
            app.S2Spinner.Step = 0.05;
            app.S2Spinner.Limits = [0.05 0.35];
            app.S2Spinner.ValueChangedFcn = createCallbackFcn(app, @S2SpinnerValueChanged, true);
            app.S2Spinner.Value = 0.05;
            app.outputPlot = uiaxes(app.mainApp);
            title(app.outputPlot, '')
            xlabel(app.outputPlot, 'Index (n)')
            ylabel(app.outputPlot, 'y[n]')
            app.outputPlot.XTick = [-10 -5 0 5 10 15 20 25 30];
            app.outputPlot.XTickLabel = {'-10'; '-5'; '0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.signal2Plot = uiaxes(app.mainApp);
            title(app.signal2Plot, '')
            xlabel(app.signal2Plot, '')
            ylabel(app.signal2Plot, 'h [n - k]')
            app.signal2Plot.XLim = [-11 30];
            app.signal2Plot.XTick = [-10 0 10 20 30];
            app.signal2Plot.XTickLabel = {''; ''; ''; ''; ''};
            app.signal3Plot = uiaxes(app.mainApp);
            title(app.signal3Plot, '')
            xlabel(app.signal3Plot, 'Index (k)')
            ylabel(app.signal3Plot, 'x[n] h[n - k]')
            app.signal3Plot.XLim = [-11 30];
            app.signal3Plot.XTick = [-10 0 10 20 30];
            app.signal3Plot.XTickLabel = {'-10'; '0'; '10'; '20'; '30'};
            app.SumEditFieldLabel = uilabel(app.mainApp);
            app.SumEditFieldLabel.Text = 'Sum';
            app.SumEditField = uieditfield(app.mainApp, 'numeric');
            app.SumEditField.Editable = 'off';
            app.SumEditFieldLabel_2 = uilabel(app.mainApp);
            app.SumEditFieldLabel_2.Text = 'Output Signal';
            % Show the figure after all components are created
            app.mainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiscreteConvolution_Dark

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainApp)
        end
    end
end